classdef FociEditorGuiAD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BottomPanel           matlab.ui.container.Panel
        Reset                 matlab.ui.control.Button
        Load                  matlab.ui.control.Button
        Save                  matlab.ui.control.Button
        Image                 matlab.ui.control.Image
        RightPanel            matlab.ui.container.Panel
        ApplyAllButton        matlab.ui.control.Button
        VoltageEdit           matlab.ui.control.NumericEditField
        BurstPerFociEdit      matlab.ui.control.NumericEditField
        BurstDurationEdit     matlab.ui.control.NumericEditField
        PRFEdit               matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        Tittle                matlab.ui.control.Label
        DutyCycle             matlab.ui.control.NumericEditField
        DutycycleLabel        matlab.ui.control.Label
        FociNumber            matlab.ui.control.DropDown
        FocinumberLabel       matlab.ui.control.Label
        HIFUVoltage           matlab.ui.control.Slider
        VoltageLabel          matlab.ui.control.Label
        BurstPerFoci          matlab.ui.control.Slider
        BurstperfociLabel     matlab.ui.control.Label
        BurstDuration         matlab.ui.control.Slider
        BurstdurationmsLabel  matlab.ui.control.Label
        PRF                   matlab.ui.control.Slider
        PRFHzLabel            matlab.ui.control.Label
        CenterPanel           matlab.ui.container.Panel
        HIFUAxes              matlab.ui.control.UIAxes
        LeftPanel             matlab.ui.container.Panel
        UIAxes_2              matlab.ui.control.UIAxes
    end

    
    properties (Access = private, Constant)
        LeftPanelHeight = 0.75;
        LeftPanelWidth = 0.4;
        CenterPanelHeight = 0.75;
        CenterPanelWidth = 0.4;
        RightPanelHeight = 1.0;
        RightPanelWidth = 0.2;
        BottomPanelHeight = 0.25;
        BottomPanelWidth = 0.8;
        MinSize = [720,480];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            app.UIFigure.Position(3:4) = max(app.UIFigure.Position(3:4), app.MinSize);
            movegui(app.UIFigure)
            
            position = app.UIFigure.Position;
            h = position(4);
            w = position(3);
            app.BottomPanel.Position = [1,1,round(app.BottomPanelWidth*w)-1, round(app.BottomPanelHeight*h)-1];
            app.LeftPanel.Position = [1, round(app.BottomPanelHeight*h)+1, round(app.LeftPanelWidth*w)-1, round(app.LeftPanelHeight*h)-1];
            app.CenterPanel.Position = [round(app.LeftPanelWidth*w)+1, round(app.BottomPanelHeight*h)+1, round(app.CenterPanelWidth*w)-1, round(app.CenterPanelHeight*h)-1];
            app.RightPanel.Position = [round(app.BottomPanelWidth*w)+1, 0, round(app.RightPanelWidth*w)-1, round(app.RightPanelHeight*h)-1];
        end

        % Button down function: HIFUAxes
        function HIFUAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.LeftPanel = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.LeftPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.CenterPanel = uipanel(app.UIFigure);
            app.HIFUAxes = uiaxes(app.CenterPanel);
            title(app.HIFUAxes, 'Title')
            xlabel(app.HIFUAxes, 'X')
            ylabel(app.HIFUAxes, 'Y')
            zlabel(app.HIFUAxes, 'Z')
            app.HIFUAxes.ButtonDownFcn = createCallbackFcn(app, @HIFUAxesButtonDown, true);
            app.RightPanel = uipanel(app.UIFigure);
            app.PRFHzLabel = uilabel(app.RightPanel);
            app.PRFHzLabel.Tag = 'PRFLabel';
            app.PRFHzLabel.Text = 'PRF (Hz)';
            app.PRF = uislider(app.RightPanel);
            app.PRF.Limits = [0.1 10];
            app.PRF.MajorTicks = [];
            app.PRF.MinorTicks = [];
            app.PRF.Value = 1;
            app.BurstdurationmsLabel = uilabel(app.RightPanel);
            app.BurstdurationmsLabel.Text = 'Burst duration (ms)';
            app.BurstDuration = uislider(app.RightPanel);
            app.BurstDuration.Limits = [0 10000];
            app.BurstDuration.MajorTicks = [];
            app.BurstDuration.MinorTicks = [];
            app.BurstDuration.Value = 500;
            app.BurstperfociLabel = uilabel(app.RightPanel);
            app.BurstperfociLabel.Text = 'Burst per foci';
            app.BurstPerFoci = uislider(app.RightPanel);
            app.BurstPerFoci.Limits = [0 20];
            app.BurstPerFoci.MajorTicks = [];
            app.BurstPerFoci.MinorTicks = [];
            app.BurstPerFoci.Value = 5;
            app.VoltageLabel = uilabel(app.RightPanel);
            app.VoltageLabel.Text = 'Voltage';
            app.HIFUVoltage = uislider(app.RightPanel);
            app.HIFUVoltage.Limits = [1.6 50];
            app.HIFUVoltage.MajorTicks = [];
            app.HIFUVoltage.MinorTicks = [];
            app.HIFUVoltage.Value = 5;
            app.FocinumberLabel = uilabel(app.RightPanel);
            app.FocinumberLabel.Text = 'Foci number';
            app.FociNumber = uidropdown(app.RightPanel);
            app.FociNumber.Items = {'1'};
            app.FociNumber.Value = '1';
            app.DutycycleLabel = uilabel(app.RightPanel);
            app.DutycycleLabel.Text = 'Duty cycle';
            app.DutyCycle = uieditfield(app.RightPanel, 'numeric');
            app.DutyCycle.Limits = [0 100];
            app.DutyCycle.ValueDisplayFormat = '%.2f';
            app.DutyCycle.Editable = 'off';
            app.Tittle = uilabel(app.RightPanel);
            app.Tittle.Text = 'HIFU Parameters';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            app.PRFEdit = uieditfield(app.RightPanel, 'numeric');
            app.PRFEdit.Limits = [0.1 10];
            app.PRFEdit.ValueDisplayFormat = '%.1f';
            app.PRFEdit.Value = 1;
            app.BurstDurationEdit = uieditfield(app.RightPanel, 'numeric');
            app.BurstDurationEdit.Limits = [0 10000];
            app.BurstDurationEdit.ValueDisplayFormat = '%.2f';
            app.BurstDurationEdit.Value = 500;
            app.BurstPerFociEdit = uieditfield(app.RightPanel, 'numeric');
            app.BurstPerFociEdit.Limits = [0 20];
            app.BurstPerFociEdit.RoundFractionalValues = 'on';
            app.BurstPerFociEdit.ValueDisplayFormat = '%.0f';
            app.BurstPerFociEdit.Value = 5;
            app.VoltageEdit = uieditfield(app.RightPanel, 'numeric');
            app.VoltageEdit.Limits = [1.6 50];
            app.VoltageEdit.ValueDisplayFormat = '%.1f';
            app.VoltageEdit.Value = 5;
            app.ApplyAllButton = uibutton(app.RightPanel, 'push');
            app.ApplyAllButton.Text = 'Apply All';
            app.BottomPanel = uipanel(app.UIFigure);
            app.Image = uiimage(app.BottomPanel);
            app.Save = uibutton(app.BottomPanel, 'push');
            app.Save.Text = 'Save';
            app.Load = uibutton(app.BottomPanel, 'push');
            app.Load.Text = 'Load';
            app.Reset = uibutton(app.BottomPanel, 'push');
            app.Reset.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FociEditorGuiAD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end