classdef VolumeSlicerGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        DimOnePanel     matlab.ui.container.Panel
        DimOneSlider    matlab.ui.control.Slider
        SliderLabel     matlab.ui.control.Label
        DimOneUIAxes    matlab.ui.control.UIAxes
        DimTwoPanel     matlab.ui.container.Panel
        DimTwoSlider    matlab.ui.control.Slider
        Slider_4Label   matlab.ui.control.Label
        DimTwoUIAxes    matlab.ui.control.UIAxes
        DimThreePanel   matlab.ui.container.Panel
        DimThreeSlider  matlab.ui.control.Slider
        Slider_3Label   matlab.ui.control.Label
        DimThreeUIAxes  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, axesToDisplay)
             %this.Gui.UIFigure.Position = [100 100 (400 .* this.NumberOfAxes) 500];

            if axesToDisplay(1) == true
                app.DimOneSlider.Enable  = 'on';
                app.DimOneSlider.Visible = 'on';
                app.DimOneUIAxes.Visible = 'on';
            end

            if axesToDisplay(2) == true
                app.DimTwoSlider.Enable  = 'on';
                app.DimTwoSlider.Visible = 'on';
                app.DimTwoUIAxes.Visible = 'on';
            end

            if axesToDisplay(3) == true
                app.DimThreeSlider.Enable  = 'on';
                app.DimThreeSlider.Visible = 'on';
                app.DimThreeUIAxes.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DimThreePanel = uipanel(app.UIFigure);
            app.DimThreePanel.Title = 'Dimension Three';
            app.DimThreeUIAxes = uiaxes(app.DimThreePanel);
            title(app.DimThreeUIAxes, 'Title')
            xlabel(app.DimThreeUIAxes, 'X')
            ylabel(app.DimThreeUIAxes, 'Y')
            zlabel(app.DimThreeUIAxes, 'Z')
            app.DimThreeUIAxes.Visible = 'off';
            app.Slider_3Label = uilabel(app.DimThreePanel);
            app.Slider_3Label.Enable = 'off';
            app.Slider_3Label.Visible = 'off';
            app.Slider_3Label.Text = 'Slider';
            app.DimThreeSlider = uislider(app.DimThreePanel);
            app.DimThreeSlider.Enable = 'off';
            app.DimThreeSlider.Visible = 'off';
            app.DimTwoPanel = uipanel(app.UIFigure);
            app.DimTwoPanel.Title = 'Dimension Two';
            app.DimTwoUIAxes = uiaxes(app.DimTwoPanel);
            title(app.DimTwoUIAxes, 'Title')
            xlabel(app.DimTwoUIAxes, 'X')
            ylabel(app.DimTwoUIAxes, 'Y')
            zlabel(app.DimTwoUIAxes, 'Z')
            app.DimTwoUIAxes.Visible = 'off';
            app.Slider_4Label = uilabel(app.DimTwoPanel);
            app.Slider_4Label.Enable = 'off';
            app.Slider_4Label.Visible = 'off';
            app.Slider_4Label.Text = 'Slider';
            app.DimTwoSlider = uislider(app.DimTwoPanel);
            app.DimTwoSlider.Enable = 'off';
            app.DimTwoSlider.Visible = 'off';
            app.DimOnePanel = uipanel(app.UIFigure);
            app.DimOnePanel.Title = 'Dimension One';
            app.DimOneUIAxes = uiaxes(app.DimOnePanel);
            title(app.DimOneUIAxes, 'Title')
            xlabel(app.DimOneUIAxes, 'X')
            ylabel(app.DimOneUIAxes, 'Y')
            zlabel(app.DimOneUIAxes, 'Z')
            app.DimOneUIAxes.Visible = 'off';
            app.SliderLabel = uilabel(app.DimOnePanel);
            app.SliderLabel.Enable = 'off';
            app.SliderLabel.Visible = 'off';
            app.SliderLabel.Text = 'Slider';
            app.DimOneSlider = uislider(app.DimOnePanel);
            app.DimOneSlider.Enable = 'off';
            app.DimOneSlider.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VolumeSlicerGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end