classdef ModeSelectionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ButtonCancel       matlab.ui.control.Button
        ButtonOK           matlab.ui.control.Button
        LabelInfo          matlab.ui.control.Label
        ButtonRemoveTab    matlab.ui.control.Button
        ButtonAddTab       matlab.ui.control.Button
        TabGroup           matlab.ui.container.TabGroup
        Tab                matlab.ui.container.Tab
        GridMain           matlab.ui.container.GridLayout
        GridLayout2        matlab.ui.container.GridLayout
        CustomModePanel_2  matlab.ui.container.Panel
        GridCustom_2       matlab.ui.container.GridLayout
        CustomModePanel    matlab.ui.container.Panel
        GridCustom         matlab.ui.container.GridLayout
        GridWholeSkip2     matlab.ui.container.GridLayout
        GridHalfSkip2      matlab.ui.container.GridLayout
        LabelWholeSkip     matlab.ui.control.Label
        GridWholeSkip1     matlab.ui.container.GridLayout
        GridHalfSkip1      matlab.ui.container.GridLayout
        GridDirect         matlab.ui.container.GridLayout
        GridLayout         matlab.ui.container.GridLayout
        ButtonRemoveMode   matlab.ui.control.Button
        ButtonNewMode      matlab.ui.control.Button
        ImageHalfSkip      matlab.ui.control.Image
        ImageDirect        matlab.ui.control.Image
        LabelCustom        matlab.ui.control.Label
        LabelHalfskip      matlab.ui.control.Label
        LabelDirect        matlab.ui.control.Label
    end

    
    methods(Access=public)
        
        function clearInfoLabel(app)
            app.LabelInfo.Text = '';
            drawnow();
        end
        
        function setInfoLabelText(app, text, color)
            if nargin < 3
                color = [1.00,0.53,0.53];
            end
            app.LabelInfo.Text = text;
            app.LabelInfo.FontColor = color;
            drawnow();
        end
        
        function disableModeButtons(app)
        %     
        
            app.ButtonAddTab.Enable = 'off';
            app.ButtonRemoveTab.Enable = 'off';
            drawnow();
        end
        
        function enableModeButtons(app)
        %
        
            app.ButtonAddTab.Enable = 'on';
            app.ButtonRemoveTab.Enable = 'on';
            drawnow();
            
        end
        
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Selection change function: TabGroup
        function TabSelectionChanged(app, event)
            %selectedTab = app.TabGroup.SelectedTab;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Mode Selection';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Mode 1';
            app.GridMain = uigridlayout(app.Tab);
            app.LabelDirect = uilabel(app.GridMain);
            app.LabelDirect.Tag = 'LabelDirect';
            app.LabelDirect.Text = 'Direct';
            app.LabelHalfskip = uilabel(app.GridMain);
            app.LabelHalfskip.Tag = 'LabelHalfskip';
            app.LabelHalfskip.Text = 'Half-skip';
            app.LabelCustom = uilabel(app.GridMain);
            app.LabelCustom.Tag = 'LabelCustom';
            app.LabelCustom.Text = 'Custom';
            app.ImageDirect = uiimage(app.GridMain);
            app.ImageDirect.ImageSource = 'direct2.png';
            app.ImageHalfSkip = uiimage(app.GridMain);
            app.ImageHalfSkip.ImageSource = 'halfskip2.png';
            app.GridLayout = uigridlayout(app.GridMain);
            app.ButtonNewMode = uibutton(app.GridLayout, 'push');
            app.ButtonNewMode.Text = 'Add new mode';
            app.ButtonRemoveMode = uibutton(app.GridLayout, 'push');
            app.ButtonRemoveMode.Text = 'Remove Mode';
            app.GridDirect = uigridlayout(app.GridMain);
            app.GridDirect.Tag = 'GridDirect';
            app.GridHalfSkip1 = uigridlayout(app.GridMain);
            app.GridHalfSkip1.Tag = 'GridHalfSkip1';
            app.GridWholeSkip1 = uigridlayout(app.GridMain);
            app.GridWholeSkip1.Tag = 'GridWholeSkip1';
            app.LabelWholeSkip = uilabel(app.GridMain);
            app.LabelWholeSkip.Tag = 'LabelWholeskip';
            app.LabelWholeSkip.Text = 'Whole-skip';
            app.GridHalfSkip2 = uigridlayout(app.GridMain);
            app.GridHalfSkip2.Tag = 'GridHalfSkip2';
            app.GridWholeSkip2 = uigridlayout(app.GridMain);
            app.GridWholeSkip2.Tag = 'GridWholeSkip2';
            app.GridLayout2 = uigridlayout(app.GridMain);
            app.CustomModePanel = uipanel(app.GridLayout2);
            app.CustomModePanel.Title = 'Custom MOdes';
            app.GridCustom = uigridlayout(app.CustomModePanel);
            app.GridCustom.Tag = 'GridCustom';
            app.CustomModePanel_2 = uipanel(app.GridLayout2);
            app.CustomModePanel_2.Title = 'Custom MOdes';
            app.GridCustom_2 = uigridlayout(app.CustomModePanel_2);
            app.GridCustom_2.Tag = 'GridCustom';
            app.ButtonAddTab = uibutton(app.UIFigure, 'push');
            app.ButtonAddTab.Tooltip = {'Adds a new mode to the end of the list'};
            app.ButtonAddTab.Text = '+';
            app.ButtonRemoveTab = uibutton(app.UIFigure, 'push');
            app.ButtonRemoveTab.Tooltip = {'Removes the current selected Mode'};
            app.ButtonRemoveTab.Text = {'-'; ''};
            app.LabelInfo = uilabel(app.UIFigure);
            app.LabelInfo.Text = '';
            app.ButtonOK = uibutton(app.UIFigure, 'push');
            app.ButtonOK.Text = 'OK';
            app.ButtonCancel = uibutton(app.UIFigure, 'push');
            app.ButtonCancel.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModeSelectionGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end